classdef histogramEqualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnhanceButton               matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        HISTOGRAMEQUALIZATIONLabel  matlab.ui.control.Label
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile("*.*", "Select an image to enhance");
            filename = strcat(pathname, filename);
            a = imread(filename);
            %a = rgb2gray(a);
            imshow(a, "Parent",app.UIAxes);
            [aCount, aLoc] = imhist(a);
            plot(app.UIAxes5, aCount, '-r');
            %hold(app.UIAxes3);
        end

        % Button pushed function: EnhanceButton
        function EnhanceButtonPushed(app, event)
            global a;
            j = rgb2gray(a)
            nJ = histeq(j);
            imshow(nJ, "Parent",app.UIAxes3);
            [jCount, jLoc] = imhist(nJ);
            plot(app.UIAxes4, jCount, "--b")
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Enhanced Image')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Equalized Histogram')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Original Histogram')
            app.HISTOGRAMEQUALIZATIONLabel = uilabel(app.UIFigure);
            app.HISTOGRAMEQUALIZATIONLabel.Text = 'HISTOGRAM EQUALIZATION';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.EnhanceButton = uibutton(app.UIFigure, 'push');
            app.EnhanceButton.ButtonPushedFcn = createCallbackFcn(app, @EnhanceButtonPushed, true);
            app.EnhanceButton.Text = 'Enhance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogramEqualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end